classdef TestCaseCompiler_Layout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        QuitMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        HelpMenu_2                   matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        RubricTabGroup               matlab.ui.container.TabGroup
        SubTypeTab                   matlab.ui.container.Tab
        SupportingFilesPanel         matlab.ui.container.Panel
        SupportingFilesAddButton     matlab.ui.control.Button
        SupportingFilesRemoveButton  matlab.ui.control.Button
        SupportingFilesListBox       matlab.ui.control.ListBox
        ValuesTabGroup               matlab.ui.container.TabGroup
        TestCase1Tab                 matlab.ui.container.Tab
        OutputBaseWordsPanel         matlab.ui.container.Panel
        OutputBaseWordsEditField     matlab.ui.control.EditField
        RemoveTestCaseButton         matlab.ui.control.Button
        AddTestCaseButton            matlab.ui.control.Button
        ProblemSettingsPanel         matlab.ui.container.Panel
        RecursiveCheckBox            matlab.ui.control.CheckBox
        BannedFunctionsLabel         matlab.ui.control.Label
        BannedFunctionsListBox       matlab.ui.control.ListBox
        BannedFunctionsAddButton     matlab.ui.control.Button
        BannedFunctionsRemoveButton  matlab.ui.control.Button
        BannedFunctionsEditField     matlab.ui.control.EditField
        GeneralPanel                 matlab.ui.container.Panel
        FunctionLabel                matlab.ui.control.Label
        FunctionBrowseButton         matlab.ui.control.Button
        SavetoLabel                  matlab.ui.control.Label
        OutputFolderBrowseButton     matlab.ui.control.Button
        FunctionNameField            matlab.ui.control.EditField
        LocalDiskCheckBox            matlab.ui.control.CheckBox
        GoogleDriveCheckBox          matlab.ui.control.CheckBox
        LocalBrowseButton            matlab.ui.control.Button
        CompileButton                matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        %% getLayout Gets the position of every element in this app
        %
        % This is used to get around the shittiness of mlapps. In TestCaseCompiler.m, this 
        % function is called in createComponents() to get the layouts of things from this app.
        % That way, AppDesigner's graphical interface can still be used to move stuff around,
        % but the controller code is kept in the .m file.
        function results = getLayout(app)
            results = struct();
            children = properties(app);
%             fields = fieldnames(children);
            fields = children;
            for ind = 1:length(fields)
                child = app.(fields{ind});
                if isprop(child, 'Position')
                    results.(fields{ind}) = child.Position;
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'CS1371 Test Case Generator';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.Text = 'Quit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.RubricTabGroup = uitabgroup(app.UIFigure);
            app.SubTypeTab = uitab(app.RubricTabGroup);
            app.SubTypeTab.Title = '⚠ Student';
            app.SupportingFilesPanel = uipanel(app.SubTypeTab);
            app.SupportingFilesPanel.Title = 'Supporting Files';
            app.SupportingFilesAddButton = uibutton(app.SupportingFilesPanel, 'push');
            app.SupportingFilesAddButton.Text = 'Add...';
            app.SupportingFilesRemoveButton = uibutton(app.SupportingFilesPanel, 'push');
            app.SupportingFilesRemoveButton.Text = 'Remove';
            app.SupportingFilesListBox = uilistbox(app.SupportingFilesPanel);
            app.SupportingFilesListBox.Items = {};
            app.SupportingFilesListBox.Value = {};
            app.ValuesTabGroup = uitabgroup(app.SubTypeTab);
            app.TestCase1Tab = uitab(app.ValuesTabGroup);
            app.TestCase1Tab.Title = 'Test Case 1';
            app.OutputBaseWordsPanel = uipanel(app.SubTypeTab);
            app.OutputBaseWordsPanel.Title = 'Output Base Words';
            app.OutputBaseWordsEditField = uieditfield(app.OutputBaseWordsPanel, 'text');
            app.RemoveTestCaseButton = uibutton(app.SubTypeTab, 'push');
            app.RemoveTestCaseButton.Enable = 'off';
            app.RemoveTestCaseButton.Text = '-';
            app.AddTestCaseButton = uibutton(app.SubTypeTab, 'push');
            app.AddTestCaseButton.Text = '+';
            app.ProblemSettingsPanel = uipanel(app.UIFigure);
            app.ProblemSettingsPanel.Title = 'Problem Settings';
            app.RecursiveCheckBox = uicheckbox(app.ProblemSettingsPanel);
            app.RecursiveCheckBox.Enable = 'off';
            app.RecursiveCheckBox.Text = 'Recursive?';
            app.BannedFunctionsLabel = uilabel(app.ProblemSettingsPanel);
            app.BannedFunctionsLabel.Enable = 'off';
            app.BannedFunctionsLabel.Text = 'Banned Functions';
            app.BannedFunctionsListBox = uilistbox(app.ProblemSettingsPanel);
            app.BannedFunctionsListBox.Items = {};
            app.BannedFunctionsListBox.Enable = 'off';
            app.BannedFunctionsListBox.Value = {};
            app.BannedFunctionsAddButton = uibutton(app.ProblemSettingsPanel, 'push');
            app.BannedFunctionsAddButton.Enable = 'off';
            app.BannedFunctionsAddButton.Text = 'Add';
            app.BannedFunctionsRemoveButton = uibutton(app.ProblemSettingsPanel, 'push');
            app.BannedFunctionsRemoveButton.Enable = 'off';
            app.BannedFunctionsRemoveButton.Text = 'Remove';
            app.BannedFunctionsEditField = uieditfield(app.ProblemSettingsPanel, 'text');
            app.BannedFunctionsEditField.Enable = 'off';
            app.GeneralPanel = uipanel(app.UIFigure);
            app.GeneralPanel.Title = 'General';
            app.FunctionLabel = uilabel(app.GeneralPanel);
            app.FunctionLabel.Text = 'Function:';
            app.FunctionBrowseButton = uibutton(app.GeneralPanel, 'push');
            app.FunctionBrowseButton.Text = 'Browse...';
            app.SavetoLabel = uilabel(app.GeneralPanel);
            app.SavetoLabel.Text = 'Save to:';
            app.OutputFolderBrowseButton = uibutton(app.GeneralPanel, 'push');
            app.OutputFolderBrowseButton.Text = 'Browse...';
            app.FunctionNameField = uieditfield(app.GeneralPanel, 'text');
            app.FunctionNameField.Editable = 'off';
            app.FunctionNameField.Value = 'No problem selected!';
            app.LocalDiskCheckBox = uicheckbox(app.GeneralPanel);
            app.LocalDiskCheckBox.Text = 'Local disk';
            app.GoogleDriveCheckBox = uicheckbox(app.GeneralPanel);
            app.GoogleDriveCheckBox.Text = 'Google Drive';
            app.GoogleDriveCheckBox.Value = true;
            app.LocalBrowseButton = uibutton(app.GeneralPanel, 'push');
            app.LocalBrowseButton.Enable = 'off';
            app.LocalBrowseButton.Text = 'Browse...';
            app.CompileButton = uibutton(app.UIFigure, 'push');
            app.CompileButton.Text = '☑ Compile!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TestCaseCompiler_Layout

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end